classdef projectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SystemLog                    slrealtime.ui.control.SystemLog
        RailLabel                    matlab.ui.control.Label
        RobotToControlSwitch         matlab.ui.control.Switch
        RobotToControlSwitchLabel    matlab.ui.control.Label
        VzEditFieldLabel_2           matlab.ui.control.Label
        VyEditFieldLabel_2           matlab.ui.control.Label
        VxEditFieldLabel_2           matlab.ui.control.Label
        VzEditField_2                matlab.ui.control.NumericEditField
        VyEditField_2                matlab.ui.control.NumericEditField
        VxEditField_2                matlab.ui.control.NumericEditField
        VzEditField                  matlab.ui.control.NumericEditField
        VzEditFieldLabel             matlab.ui.control.Label
        VyEditField                  matlab.ui.control.NumericEditField
        VyEditFieldLabel             matlab.ui.control.Label
        VxEditField                  matlab.ui.control.NumericEditField
        VxEditFieldLabel             matlab.ui.control.Label
        WxButton_2                   matlab.ui.control.Button
        WyButton_2                   matlab.ui.control.Button
        WyButton                     matlab.ui.control.Button
        WxButton                     matlab.ui.control.Button
        WzButton_2                   matlab.ui.control.Button
        WzButton                     matlab.ui.control.Button
        VxButton_2                   matlab.ui.control.Button
        VyButton_2                   matlab.ui.control.Button
        VyButton                     matlab.ui.control.Button
        VxButton                     matlab.ui.control.Button
        VzButton_2                   matlab.ui.control.Button
        VzButton                     matlab.ui.control.Button
        CartesianControlLabel        matlab.ui.control.Label
        ModeSwitch                   matlab.ui.control.Switch
        ModeSwitchLabel              matlab.ui.control.Label
        STARTButton                  matlab.ui.control.Button
        DisengageButton              matlab.ui.control.Button
        ResumeButton                 matlab.ui.control.Button
        eSTOPButton                  matlab.ui.control.Button
        q6Slider                     matlab.ui.control.Slider
        q5Slider                     matlab.ui.control.Slider
        q4Slider                     matlab.ui.control.Slider
        q3Slider                     matlab.ui.control.Slider
        q2Slider                     matlab.ui.control.Slider
        q1Slider                     matlab.ui.control.Slider
        RailSlider                   matlab.ui.control.Slider
        dZSpinner                    matlab.ui.control.Spinner
        dZSpinnerLabel               matlab.ui.control.Label
        dYSpinner                    matlab.ui.control.Spinner
        dYSpinnerLabel               matlab.ui.control.Label
        dXSpinner                    matlab.ui.control.Spinner
        dXSpinnerLabel               matlab.ui.control.Label
        RollSpinner                  matlab.ui.control.Spinner
        RollSpinnerLabel             matlab.ui.control.Label
        PitchSpinner                 matlab.ui.control.Spinner
        PitchSpinnerLabel            matlab.ui.control.Label
        YawSpinner                   matlab.ui.control.Spinner
        YawSpinnerLabel              matlab.ui.control.Label
        JointPositionControlLabel    matlab.ui.control.Label
        JointControlCheckBox         matlab.ui.control.CheckBox
        EndEffectorControlCheckBox   matlab.ui.control.CheckBox
        TrajectoryGeneratorDropDown  matlab.ui.control.DropDown
        EndEffectorStepLabel         matlab.ui.control.Label
        ExecuteButton                matlab.ui.control.Button
        PlanButton                   matlab.ui.control.Button
        q7SliderLabel                matlab.ui.control.Label
        q6SliderLabel                matlab.ui.control.Label
        q5SliderLabel                matlab.ui.control.Label
        q4SliderLabel                matlab.ui.control.Label
        q3SliderLabel                matlab.ui.control.Label
        q2SliderLabel                matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       TM5;
       UR3e;
       qMatrix;
       qMatrixTM5;
       qMatrixUR3e;
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            location = {
                        [0,0,0]
                        [1.25,1,0.324] %Bench_Omran %BECAUSE OF ROTATION OF Z THIS ONE HAS NEGATIVE BUT IT IS [1.25,1,0.324]
                        [1.25,2,0.324]   %Bench_Print
                        [-1.25,1,0.324]  %Bench_UR3E
            
                        [-0.5,0.603,1.445] %Bench_UR3E_BinR1
                        [-1,0.603,1.445] %Bench_UR3E_BinR2
                        [-0.5,1.397,1.445] %Bench_UR3E_BinL1
                        [-1,1.397,1.445] %Bench_UR3E_BinL2
            
                        [0.3,1.85,1.343]   %Bench_Print_Printer1
                        [.9,1.85,1.343]   %Bench_Print_Printer2
                        [1.5,1.85,1.343]   %Bench_Print_Printer3
                        
                       }; 
            
            hold on
            PlaceObject('Garage.PLY',location{1});
           
            PlaceObject('Bench.PLY',location{2});
            PlaceObject('Bench.PLY',location{3});
            PlaceObject('WBench.PLY',location{4});
            
            PlaceObject('BinR.ply',location{5});
            PlaceObject('BinR.ply',location{6});
            PlaceObject('BinL.ply',location{7});
            PlaceObject('BinL.ply',location{8});
            
            printer_1 = printerSpawn(location{9});
            printer_2 = printerSpawn(location{10});
            printer_3 = printerSpawn(location{11});
            
            app.TM5 = LinearTM5();
            app.UR3e = ur3e_modified();

            urEndPose = app.UR3e.model.fkine(app.UR3e.model.getpos());
            gripL = g_2F85;
            gripR = g_2F85;
            
            gripR.model.base = urEndPose.T*trotx(-pi/2)*trotz(-pi);
            gripL.model.base = urEndPose.T*trotx(pi/2);
            gripL.model.animate([0,0,0]);
            gripR.model.animate([0,0,0]);
            
            pb1 = printBed();
            
            pb1.bedModel{1}.base = transl(.275,1.806,1.354);
            
            pb1.UpdateModel();


        end

        % Button pushed function: PlanButton
        function PlanButtonPushed(app, event)
           if app.EndEffectorControlCheckBox == 0
               dX = app.dXSpinner.Value;
               dY = app.dYSpinner.Value;
               dZ = app.dZSpinner.Value;
               dYaw = app.YawSpinner.Value;
               dPitch = app.PitchSpinner.Value;
               dRoll = app.RollSpinner.Value;

               transformation = transl(dX,dY,dZ) * rpy2tr(deg2rad(dYaw),deg2rad(dPitch),deg2rad(dRoll))
               disp(transformation);
               %trap ikine
               if app.TrajectoryGeneratorDropDown == 1
                   if app.RobotToControlSwitch == 'Off'
                       %UR3e End-Effector Controlled
                       targetPose = app.UR3e.model.fkine(UR3e.model.getpos()) * SE3(transformation);
                       app.qMatrixUR3e = app.UR3e.trap_ikine(app.UR3e,app.UR3e.model.getpos(),0,targetPose,[1 1 1 1 1 1],50);
                   elseif app.RobotToControlSwitch == 'On'
                       %TM5 End-Effector Controlled
                       targetPose = app.TM5.model.fkine(TM5.model.getpos()) * SE3(transformation);
                       app.qMatrixTM5 = app.TM5.trap_ikine(app.TM5,app.TM5.model.getpos(),0,targetPose,[1 1 1 1 1 1],50);
                   end
               %trap ikcon
               elseif app.TrajectoryGeneratorDropDown == 2
                   if app.RobotToControlSwitch == 'Off'
                       %UR3e End-Effector Controlled
                       targetPose = app.UR3e.model.fkine(UR3e.model.getpos()) * SE3(transformation);

                       app.qMatrixUR3e = app.UR3e.trap_ikcon(app.UR3e,app.UR3e.model.getpos(),0,targetPose,50);

                   elseif app.RobotToControlSwitch == 'On'
                       %TM5 End-Effector Controlled
                       targetPose = app.TM5.model.fkine(TM5.model.getpos()) * SE3(transformation);

                       app.qMatrixTM5 = app.TM5.trap_ikcon(app.TM5,app.TM5.model.getpos(),0,targetPose,50);
                   end
               %Quintic ikine
               elseif app.TrajectoryGeneratorDropDown == 3
                   if app.RobotToControlSwitch == 'Off'
                       %UR3e End-Effector Controlled
                       targetPose = app.UR3e.model.fkine(UR3e.model.getpos()) * SE3(transformation);
                       app.qMatrixUR3e = app.UR3e.quintic_ikine(app.UR3e,app.UR3e.model.getpos(),0,targetPose,[1 1 1 1 1 1],50);
                   elseif app.RobotToControlSwitch == 'On'
                       %TM5 End-Effector Controlled
                       targetPose = app.TM5.model.fkine(TM5.model.getpos()) * SE3(transformation);
                       app.qMatrixTM5 = app.TM5.quintic_ikine(app.TM5,app.TM5.model.getpos(),0,targetPose,[1 1 1 1 1 1],50);
                   end
               %Quintic ikcon
               elseif app.TrajectoryGeneratorDropDown == 4
                   if app.RobotToControlSwitch == 'Off'
                       %UR3e End-Effector Controlled
                       targetPose = app.UR3e.model.fkine(UR3e.model.getpos()) * SE3(transformation);

                       app.qMatrixUR3e = app.UR3e.quintic_ikcon(app.UR3e,app.UR3e.model.getpos(),0,targetPose,50);

                   elseif app.RobotToControlSwitch == 'On'
                       %TM5 End-Effector Controlled
                       targetPose = app.TM5.model.fkine(TM5.model.getpos()) * SE3(transformation);

                       app.qMatrixTM5 = app.TM5.quintic_ikcon(app.TM5,app.TM5.model.getpos(),0,targetPose,50);
                   end
               end
           elseif app.JointControlCheckBox ==   1
               if app.RobotToControlSwitch == 'Off'
                   %UR3e Joint Controlled          
                   q1 = app.q1Slider.Value;
                   q2 = app.q2Slider.Value;
                   q3 = app.q3Slider.Value;
                   q4 = app.q4Slider.Value;
                   q5 = app.q5Slider.Value;
                   q6 = app.q6Slider.Value;
                   jointState = [q1,q2,q3,q4,q5,q6];
                   targetPose = app.UR3e.model.fkine(jointState);
                   if app.TrajectoryGeneratorDropDown == 1
                       app.qMatrixUR3e = app.UR3e.trap_ikine(app.UR3e,app.UR3e.model.getpos(),0,targetPose,[1 1 1 1 1 1],50);
                   elseif app.TrajectoryGeneratorDropDown == 2
                       app.qMatrixUR3e = app.UR3e.trap_ikcon(app.UR3e,app.UR3e.model.getpos(),0,targetPose,50);
                   elseif app.TrajectoryGeneratorDropDown == 3
                       app.qMatrixUR3e = app.UR3e.quintic_ikine(app.UR3e,app.UR3e.model.getpos(),0,targetPose,[1 1 1 1 1 1],50);
                   elseif app.TrajectoryGeneratorDropDown == 4
                       app.qMatrixUR3e = app.UR3e.quintic_ikcon(app.UR3e,app.UR3e.model.getpos(),0,targetPose,50);
                   end
               elseif app.RobotToControlSwitch == 'On'
                   %TM5 Joint Controlled
                   q1 = app.RailSlider;
                   q2 = app.q1Slider.Value;
                   q3 = app.q2Slider.Value;
                   q4 = app.q3Slider.Value;
                   q5 = app.q4Slider.Value;
                   q6 = app.q5Slider.Value;
                   q7 = app.q6Slider.Value;
                   jointState = [q1,q2,q3,q4,q5,q6,q7];
                   targetPose = app.TM5.model.fkine(jointState);
                   if app.TrajectoryGeneratorDropDown == 1
                       app.qMatrixTM5 = app.TM5.trap_ikine(app.TM5,app.TM5.model.getpos(),0,targetPose,[1 1 1 1 1 1],50);
                   elseif app.TrajectoryGeneratorDropDown == 2
                       app.qMatrixTM5 = app.TM5.trap_ikcon(app.TM5,app.TM5.model.getpos(),0,targetPose,50);
                   elseif app.TrajectoryGeneratorDropDown == 3
                       app.qMatrixTM5 = app.TM5.quintic_ikine(app.TM5,app.TM5.model.getpos(),0,targetPose,[1 1 1 1 1 1],50);
                   elseif app.TrajectoryGeneratorDropDown == 4
                       app.qMatrixTM5 = app.TM5.quintic_ikcon(app.TM5,app.TM5.model.getpos(),0,targetPose,50);
                   end
               end
           end
           %plot the planned path
           pointsMatrix = zeros(length(app.qMatrix),3);

           if app.RobotToControlSwitch == 'Off'
               for i = 1:length(app.qMatrixUR3e)
                    point = app.UR3e.model.fkine(app.qMatrixUR3e(i,:)).T
                    pointsMatrix(i,:) = point(1:3,4)'
               end
           elseif app.RobotToControlSwitch == 'On'
               for i = 1:length(app.qMatrixTM5)
                    point = app.TM5.model.fkine(app.qMatrixTM5(i,:)).T
                    pointsMatrix(i,:) = point(1:3,4)'
               end
           end
            x = pointsMatrix(:,1);
            y = pointsMatrix(:,2);
            z = pointsMatrix(:,3);
            % axis equal;
            % hold on
            % plot3(axes,x,y,z,'-o','LineWidth', 5)
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            if app.RobotToControlSwitch == 'Off'
                %UR3e
                for i = 1:length(app.qMatrixUR3e)
                    %Collision check here
                    app.UR3e.model.animate(app.qMatrixUR3e(i,:))
                    drawnow();
                    pause(0.05);
                end
            elseif app.RobotToControlSwitch == 'On'
                %TM5
                 for i = 1:length(app.qMatrixTM5)
                    %Collision check here
                    app.TM5.model.animate(app.qMatrixTM5(i,:))
                    drawnow();
                    pause(0.05);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Environment')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q1';
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q2';
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q3';
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q4';
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q5';
            app.q7SliderLabel = uilabel(app.UIFigure);
            app.q7SliderLabel.Text = 'q6';
            app.PlanButton = uibutton(app.UIFigure, 'push');
            app.PlanButton.ButtonPushedFcn = createCallbackFcn(app, @PlanButtonPushed, true);
            app.PlanButton.Text = 'Plan';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.EndEffectorStepLabel = uilabel(app.UIFigure);
            app.EndEffectorStepLabel.Text = 'End-Effector Step';
            app.TrajectoryGeneratorDropDown = uidropdown(app.UIFigure);
            app.TrajectoryGeneratorDropDown.Items = {'Trapezoidal ikine', 'Trapezoidal ikcon', 'Quintic ikine', 'Quintic ikcon'};
            app.TrajectoryGeneratorDropDown.Value = 'Trapezoidal ikcon';
            app.EndEffectorControlCheckBox = uicheckbox(app.UIFigure);
            app.EndEffectorControlCheckBox.Text = 'End Effector Control';
            app.JointControlCheckBox = uicheckbox(app.UIFigure);
            app.JointControlCheckBox.Text = 'Joint Control';
            app.JointPositionControlLabel = uilabel(app.UIFigure);
            app.JointPositionControlLabel.Text = 'Joint Position Control';
            app.YawSpinnerLabel = uilabel(app.UIFigure);
            app.YawSpinnerLabel.Text = 'Yaw';
            app.YawSpinner = uispinner(app.UIFigure);
            app.PitchSpinnerLabel = uilabel(app.UIFigure);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.PitchSpinner = uispinner(app.UIFigure);
            app.RollSpinnerLabel = uilabel(app.UIFigure);
            app.RollSpinnerLabel.Text = 'Roll';
            app.RollSpinner = uispinner(app.UIFigure);
            app.dXSpinnerLabel = uilabel(app.UIFigure);
            app.dXSpinnerLabel.Text = 'dX';
            app.dXSpinner = uispinner(app.UIFigure);
            app.dYSpinnerLabel = uilabel(app.UIFigure);
            app.dYSpinnerLabel.Text = 'dY';
            app.dYSpinner = uispinner(app.UIFigure);
            app.dZSpinnerLabel = uilabel(app.UIFigure);
            app.dZSpinnerLabel.Text = 'dZ';
            app.dZSpinner = uispinner(app.UIFigure);
            app.RailSlider = uislider(app.UIFigure);
            app.RailSlider.Limits = [-0.8 -0.01];
            app.RailSlider.Value = -0.01;
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-270 270];
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-180 180];
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-180 180];
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-180 180];
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-180 180];
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.Limits = [-270 270];
            app.eSTOPButton = uibutton(app.UIFigure, 'push');
            app.eSTOPButton.Text = 'eSTOP';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.Text = 'Resume';
            app.DisengageButton = uibutton(app.UIFigure, 'push');
            app.DisengageButton.Text = 'Disengage';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.Text = 'START';
            app.ModeSwitchLabel = uilabel(app.UIFigure);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'Manual', 'Automatic'};
            app.ModeSwitch.Value = 'Manual';
            app.CartesianControlLabel = uilabel(app.UIFigure);
            app.CartesianControlLabel.Text = 'Cartesian Control';
            app.VzButton = uibutton(app.UIFigure, 'push');
            app.VzButton.Text = 'Vz-';
            app.VzButton_2 = uibutton(app.UIFigure, 'push');
            app.VzButton_2.Text = 'Vz+';
            app.VxButton = uibutton(app.UIFigure, 'push');
            app.VxButton.Text = 'Vx+';
            app.VyButton = uibutton(app.UIFigure, 'push');
            app.VyButton.Text = 'Vy-';
            app.VyButton_2 = uibutton(app.UIFigure, 'push');
            app.VyButton_2.Text = 'Vy+';
            app.VxButton_2 = uibutton(app.UIFigure, 'push');
            app.VxButton_2.Text = 'Vx-';
            app.WzButton = uibutton(app.UIFigure, 'push');
            app.WzButton.Text = 'Wz-';
            app.WzButton_2 = uibutton(app.UIFigure, 'push');
            app.WzButton_2.Text = 'Wz+';
            app.WxButton = uibutton(app.UIFigure, 'push');
            app.WxButton.Text = 'Wx+';
            app.WyButton = uibutton(app.UIFigure, 'push');
            app.WyButton.Text = 'Wy-';
            app.WyButton_2 = uibutton(app.UIFigure, 'push');
            app.WyButton_2.Text = 'Wy+';
            app.WxButton_2 = uibutton(app.UIFigure, 'push');
            app.WxButton_2.Text = 'Wx-';
            app.VxEditFieldLabel = uilabel(app.UIFigure);
            app.VxEditFieldLabel.Text = 'Vx';
            app.VxEditField = uieditfield(app.UIFigure, 'numeric');
            app.VyEditFieldLabel = uilabel(app.UIFigure);
            app.VyEditFieldLabel.Text = 'Vy';
            app.VyEditField = uieditfield(app.UIFigure, 'numeric');
            app.VzEditFieldLabel = uilabel(app.UIFigure);
            app.VzEditFieldLabel.Text = 'Vz';
            app.VzEditField = uieditfield(app.UIFigure, 'numeric');
            app.VxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VyEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VxEditFieldLabel_2 = uilabel(app.UIFigure);
            app.VxEditFieldLabel_2.Text = 'Wx';
            app.VyEditFieldLabel_2 = uilabel(app.UIFigure);
            app.VyEditFieldLabel_2.Text = 'Wy';
            app.VzEditFieldLabel_2 = uilabel(app.UIFigure);
            app.VzEditFieldLabel_2.Text = 'Wz';
            app.RobotToControlSwitchLabel = uilabel(app.UIFigure);
            app.RobotToControlSwitchLabel.Text = 'Robot To Control';
            app.RobotToControlSwitch = uiswitch(app.UIFigure, 'slider');
            app.RobotToControlSwitch.Items = {'UR3e', 'TM5'};
            app.RobotToControlSwitch.Value = 'UR3e';
            app.RailLabel = uilabel(app.UIFigure);
            app.RailLabel.Text = 'Rail';
            app.SystemLog = slrealtime.ui.control.SystemLog(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end